classdef MapCreatorTuning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Eta3Slider       matlab.ui.control.Slider
        Eta3SliderLabel  matlab.ui.control.Label
        Eta2Slider       matlab.ui.control.Slider
        Eta2SliderLabel  matlab.ui.control.Label
        Eta1Slider       matlab.ui.control.Slider
        Eta1SliderLabel  matlab.ui.control.Label
        Psi3Slider       matlab.ui.control.Slider
        Psi3SliderLabel  matlab.ui.control.Label
        Psi2Slider       matlab.ui.control.Slider
        Psi2SliderLabel  matlab.ui.control.Label
        Psi1Slider       matlab.ui.control.Slider
        Psi1SliderLabel  matlab.ui.control.Label
        UIAxes3          matlab.ui.control.UIAxes
        UIAxes2          matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Eta1Slider, Eta2Slider, Eta3Slider, 
        % ...and 3 other components
        function SliderValueChanged(app, event)
            % Psi Graph
            phiin = [0.6,1, 1.4];
            psiin = [app.Psi1Slider.Value, app.Psi2Slider.Value, app.Psi3Slider.Value];
            scatter(app.UIAxes,phiin,psiin,'ro','filled');

            PolyPsi = polyfit(phiin,psiin,2);
            phi = linspace(0.6,1.4);
            psi = polyval(PolyPsi,phi);

            hold(app.UIAxes,'on')
            plot(app.UIAxes,phi,psi,'r')
            hold(app.UIAxes,'off')
            axis(app.UIAxes,[0 2 0 2])
            grid(app.UIAxes,'on')

            % Eta graph
            etain = [app.Eta1Slider.Value, app.Eta2Slider.Value, app.Eta3Slider.Value];
            scatter(app.UIAxes2,phiin,etain,'bo','filled');

            PolyEta = polyfit(phiin,etain,2);
            phi = linspace(0.6,1.4);
            eta = polyval(PolyEta,phi);

            hold(app.UIAxes2,'on')
            plot(app.UIAxes2,phi,eta,'b')
            hold(app.UIAxes2,'off')
            axis(app.UIAxes2,[0 2 0 2])
            grid(app.UIAxes2,'on')


            %% Setup compressor map showing
Eng = EngineModelPkg.TurbofanNonlinearSizing(EngineModelPkg.EngineSpecsPkg.PW_1919G,0);
Comp = Eng.HPCObject;
%Comp = Eng.FanSysObject.LPCObject;
Map = EngineModelPkg.MapPkg.CreateMap_V_App(Comp,PolyPsi,PolyEta);

% efficiency
contour(app.UIAxes3,Map.Mass,Map.PR,Map.Eta,40,'-')
clim(app.UIAxes3,[0, 1]);
colorbar(app.UIAxes3)
colormap(app.UIAxes3, 'bone')
grid(app.UIAxes3,'on')
hold(app.UIAxes3,'on')
scatter(app.UIAxes3,100,Comp.Pi,'ko','filled')


contour(app.UIAxes3,Map.Mass,Map.PR,Map.Rot,20,'k')


hold(app.UIAxes3,'off')

clc


        end

        % Callback function
        function Eta1SliderValueChanged(app, event)


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Work vs Flow Coefficients')
            xlabel(app.UIAxes, '\phi/\phi_{ref}')
            ylabel(app.UIAxes, '\psi/\psi_{ref}')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Efficiency vs Flow Coeff.')
            xlabel(app.UIAxes2, '\phi/\phi_{ref}')
            ylabel(app.UIAxes2, '\eta/\eta_{ref}')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Efficiency Contours')
            xlabel(app.UIAxes3, 'Corrected Mass Flow (%)')
            ylabel(app.UIAxes3, 'Pressure Ratio')
            zlabel(app.UIAxes3, 'Z')
            app.Psi1SliderLabel = uilabel(app.UIFigure);
            app.Psi1SliderLabel.Text = 'Psi 1';
            app.Psi1Slider = uislider(app.UIFigure);
            app.Psi1Slider.Limits = [0 2];
            app.Psi1Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Psi1Slider.Value = 1.21824;
            app.Psi2SliderLabel = uilabel(app.UIFigure);
            app.Psi2SliderLabel.Text = 'Psi 2';
            app.Psi2Slider = uislider(app.UIFigure);
            app.Psi2Slider.Limits = [0 2];
            app.Psi2Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Psi2Slider.Value = 1.01;
            app.Psi3SliderLabel = uilabel(app.UIFigure);
            app.Psi3SliderLabel.Text = 'Psi 3';
            app.Psi3Slider = uislider(app.UIFigure);
            app.Psi3Slider.Limits = [0 2];
            app.Psi3Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Psi3Slider.Value = 0.42;
            app.Eta1SliderLabel = uilabel(app.UIFigure);
            app.Eta1SliderLabel.Text = 'Eta 1';
            app.Eta1Slider = uislider(app.UIFigure);
            app.Eta1Slider.Limits = [0 2];
            app.Eta1Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Eta1Slider.Value = 0.688;
            app.Eta2SliderLabel = uilabel(app.UIFigure);
            app.Eta2SliderLabel.Text = 'Eta 2';
            app.Eta2Slider = uislider(app.UIFigure);
            app.Eta2Slider.Limits = [0 2];
            app.Eta2Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Eta2Slider.Value = 1;
            app.Eta3SliderLabel = uilabel(app.UIFigure);
            app.Eta3SliderLabel.Text = 'Eta 3';
            app.Eta3Slider = uislider(app.UIFigure);
            app.Eta3Slider.Limits = [0 2];
            app.Eta3Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Eta3Slider.Value = 0.529;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MapCreatorTuning

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end